classdef Continuation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        continuationtoolInstituteofDynamicsandVibrationResearchUIFigure  matlab.ui.Figure
        WatchdocumentationMenu_2        matlab.ui.container.Menu
        WatchGitHubprojectMenu_2        matlab.ui.container.Menu
        OpencontinuationfunctionMenu    matlab.ui.container.Menu
        OpensrcfolderMenu               matlab.ui.container.Menu
        ContactUsMenu                   matlab.ui.container.Menu
        ResetButton_3                   matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        GetfigureButton                 matlab.ui.control.Button
        ShowadvancedoptionsCheckBox     matlab.ui.control.CheckBox
        Button                          matlab.ui.control.Button
        EnabletoolbarinplotCheckBox     matlab.ui.control.CheckBox
        EnablemenubarinplotCheckBox     matlab.ui.control.CheckBox
        commandwindownoteditableLabel   matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        functionintegrationLabel        matlab.ui.control.Label
        PathcontinuationtoolLabel       matlab.ui.control.Label
        ImplementfunctionbyDropDown     matlab.ui.control.DropDown
        ImplementfunctionbyDropDownLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Image3                          matlab.ui.control.Image
        Function_nameEditField          matlab.ui.control.EditField
        Image2                          matlab.ui.control.Image
        Function_nameEditFieldLabel     matlab.ui.control.Label
        advancedoptionsLabel            matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        numericjacobianTab_2            matlab.ui.container.Tab
        diffquotDropDown                matlab.ui.control.DropDown
        diffquotDropDownLabel           matlab.ui.control.Label
        reversecontrolTab               matlab.ui.container.Tab
        alpha_reverseEditField          matlab.ui.control.EditField
        alpha_reverseEditFieldLabel     matlab.ui.control.Label
        reverseDropDown                 matlab.ui.control.DropDown
        reverseDropDownLabel            matlab.ui.control.Label
        include_reverseDropDown         matlab.ui.control.DropDown
        include_reverseDropDownLabel    matlab.ui.control.Label
        scalingTab                      matlab.ui.container.Tab
        dscale_minEditField             matlab.ui.control.EditField
        dscale_minEditFieldLabel        matlab.ui.control.Label
        dscale0EditField                matlab.ui.control.EditField
        dscale0EditFieldLabel           matlab.ui.control.Label
        scalingDropDown                 matlab.ui.control.DropDown
        scalingDropDownLabel            matlab.ui.control.Label
        breakTab                        matlab.ui.container.Tab
        break_functionEditField         matlab.ui.control.EditField
        break_functionEditFieldLabel    matlab.ui.control.Label
        initialdirectionTab_2           matlab.ui.container.Tab
        directionEditField              matlab.ui.control.EditField
        directionEditFieldLabel         matlab.ui.control.Label
        closedcurvedetectionTab_2       matlab.ui.container.Tab
        closed_counterEditField         matlab.ui.control.EditField
        closed_counterEditFieldLabel    matlab.ui.control.Label
        closed_curve_detectionDropDown  matlab.ui.control.DropDown
        closed_curve_detectionDropDownLabel  matlab.ui.control.Label
        homotopyTab                     matlab.ui.container.Tab
        homotopy_error_counterEditField  matlab.ui.control.EditField
        homotopy_error_counterEditFieldLabel  matlab.ui.control.Label
        homotopyDropDown                matlab.ui.control.DropDown
        homotopyDropDownLabel           matlab.ui.control.Label
        deflationTab                    matlab.ui.container.Tab
        deflation_error_counterEditField  matlab.ui.control.EditField
        deflation_error_counterEditFieldLabel  matlab.ui.control.Label
        deflationDropDown               matlab.ui.control.DropDown
        deflationDropDownLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        lambdaandloopspecificationsTab  matlab.ui.container.Tab
        approve_manuallyEditField       matlab.ui.control.EditField
        approve_manuallyEditFieldLabel  matlab.ui.control.Label
        check_residualDropDown          matlab.ui.control.DropDown
        check_residualDropDownLabel     matlab.ui.control.Label
        stepback_error_counterEditField  matlab.ui.control.EditField
        stepback_error_counterEditFieldLabel  matlab.ui.control.Label
        n_step_maxEditField             matlab.ui.control.EditField
        n_step_maxEditFieldLabel        matlab.ui.control.Label
        n_iter_optEditField             matlab.ui.control.EditField
        n_iter_optEditFieldLabel        matlab.ui.control.Label
        max_error_counterEditField      matlab.ui.control.EditField
        max_error_counterEditFieldLabel  matlab.ui.control.Label
        l_endEditField                  matlab.ui.control.EditField
        l_endEditFieldLabel             matlab.ui.control.Label
        l_startEditField                matlab.ui.control.EditField
        l_startEditFieldLabel           matlab.ui.control.Label
        v_0EditField                    matlab.ui.control.EditField
        v_0EditFieldLabel               matlab.ui.control.Label
        l_targetEditField               matlab.ui.control.EditField
        l_targetEditFieldLabel          matlab.ui.control.Label
        l_0EditField                    matlab.ui.control.EditField
        l_0EditFieldLabel               matlab.ui.control.Label
        CorrectorandstepsizemethodTab   matlab.ui.container.Tab
        enforce_ds_maxDropDown          matlab.ui.control.DropDown
        enforce_ds_maxDropDownLabel     matlab.ui.control.Label
        step_size_angleEditField        matlab.ui.control.EditField
        step_size_angleEditFieldLabel   matlab.ui.control.Label
        step_size_alpha_maxEditField    matlab.ui.control.EditField
        step_size_alpha_maxEditFieldLabel  matlab.ui.control.Label
        step_e_maxEditField             matlab.ui.control.EditField
        step_e_maxEditFieldLabel        matlab.ui.control.Label
        startstepsizeEditField          matlab.ui.control.EditField
        startstepsizeEditFieldLabel     matlab.ui.control.Label
        ds_minEditField                 matlab.ui.control.EditField
        ds_minEditFieldLabel            matlab.ui.control.Label
        ds_maxEditField                 matlab.ui.control.EditField
        ds_maxEditFieldLabel            matlab.ui.control.Label
        step_size_controlDropDown       matlab.ui.control.DropDown
        step_size_controlDropDownLabel  matlab.ui.control.Label
        correctorDropDown               matlab.ui.control.DropDown
        correctorDropDownLabel          matlab.ui.control.Label
        predictorTab                    matlab.ui.container.Tab
        correct_predictorDropDown       matlab.ui.control.DropDown
        correct_predictorDropDownLabel  matlab.ui.control.Label
        predictor_polynomial_orderEditField  matlab.ui.control.EditField
        predictor_polynomial_orderEditFieldLabel  matlab.ui.control.Label
        predictor_polynomial_fitEditField  matlab.ui.control.EditField
        predictor_polynomial_fitEditFieldLabel  matlab.ui.control.Label
        predictor_solverDropDown        matlab.ui.control.DropDown
        predictor_solverDropDownLabel   matlab.ui.control.Label
        predictor_polynomial_adaptiveDropDown  matlab.ui.control.DropDown
        predictor_polynomial_adaptiveDropDownLabel  matlab.ui.control.Label
        predictorDropDown               matlab.ui.control.DropDown
        predictorDropDownLabel          matlab.ui.control.Label
        plotdisplayTab                  matlab.ui.container.Tab
        displayDropDown                 matlab.ui.control.DropDown
        displayDropDownLabel            matlab.ui.control.Label
        live_plot_figEditField          matlab.ui.control.EditField
        live_plot_figEditFieldLabel     matlab.ui.control.Label
        plot_vars_indexEditField_2      matlab.ui.control.EditField
        plot_vars_indexEditField_2Label  matlab.ui.control.Label
        plot_var_of_interestEditField   matlab.ui.control.EditField
        plot_var_of_interestEditFieldLabel  matlab.ui.control.Label
        plot_pauseEditField             matlab.ui.control.EditField
        plot_pauseEditFieldLabel        matlab.ui.control.Label
        plotDropDown                    matlab.ui.control.DropDown
        plotDropDownLabel               matlab.ui.control.Label
        solverTab                       matlab.ui.container.Tab
        solver_tolEditField             matlab.ui.control.EditField
        solver_tolEditFieldLabel        matlab.ui.control.Label
        solver_force1itDropDown         matlab.ui.control.DropDown
        solver_force1itDropDownLabel    matlab.ui.control.Label
        solverDropDown                  matlab.ui.control.DropDown
        solverDropDownLabel             matlab.ui.control.Label
        bifurcationTab                  matlab.ui.container.Tab
        n_bif_searchEditField           matlab.ui.control.EditField
        n_bif_searchEditFieldLabel      matlab.ui.control.Label
        stop_on_bifurcationDropDown     matlab.ui.control.DropDown
        stop_on_bifurcationDropDownLabel  matlab.ui.control.Label
        bif_rand_dirDropDown            matlab.ui.control.DropDown
        bif_rand_dirDropDownLabel       matlab.ui.control.Label
        bifurcationDropDown             matlab.ui.control.DropDown
        bifurcationDropDownLabel        matlab.ui.control.Label
        basicoptionsLabel               matlab.ui.control.Label
        ResetSettingsButton             matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        Function_pathEditField          matlab.ui.control.EditField
        Function_pathEditFieldLabel     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        TextArea_3                      matlab.ui.control.TextArea
        NeedHelpTab                     matlab.ui.container.Tab
        TextArea_4                      matlab.ui.control.TextArea
        Label_2                         matlab.ui.control.Label
        IDSTidoKubatschek22092021Label  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        fig_num = NaN; % Figure number to plot in
        pos_Offset; % offset of plot figure
        pos_plot; % dimension of plot figure
        Opt;
        uisize0; % Description
    end
    
    methods (Access = private)
        
        function setPromptFcn(app,jTextArea,~,~)
           % Prevent overlapping reentry due to prompt replacement
          persistent inProgress
          if isempty(inProgress)
            inProgress = 1;  %#ok unused
          else
            return;
          end
         
          try
            % *** Prompt modification code goes here ***
            cwText = char(jTextArea.getText);
            app.TextArea_2.Value = cwText;
            scroll(app.TextArea_2,'bottom');
            % force prompt-change callback to fizzle-out...
            pause(0.02);
          catch
            % Never mind - ignore errors...
          end
         
          % Enable new callbacks now that the prompt has been modified
          inProgress = [];
         
        end  % setPromptFcn


        function getDefaultValues(app,varargin)
            part = 0; % 0 -- execute all
            if ~isempty(varargin)
                part = varargin{1}; % specify part to execute; 1 basic options, 2 advanced options
            end
            if part == 0
                %% function integration
                %
                app.Function_pathEditField.Value = 'test_cases';
                %
            end
            if part == 0 || part == 1
                %% basic options
                %
                stru = app.Opt.corrector;
                fi = fields(stru);
                val = 'off';
                for k=1:length(fi)
                    if getfield(stru, fi{k})
                        val = fi{k};
                    end
                end
                app.correctorDropDown.Value = val;
                stru = app.Opt.step_size_control;
                fi = fields(stru);
                val = 'off';
                for k=1:length(fi)
                    if getfield(stru, fi{k})
                        val = fi{k};
                    end
                end
                app.step_size_controlDropDown.Value = val;
                app.step_size_alpha_maxEditField.Value = num2str(app.Opt.step_size_alpha_max);
                app.step_size_angleEditField.Value = num2str(app.Opt.step_size_angle);
                app.step_e_maxEditField.Value = num2str(app.Opt.step_size_e_max);
                app.ds_maxEditField.Value = num2str(app.Opt.ds_max);
                app.ds_minEditField.Value = num2str(app.Opt.ds_min);
                if app.Opt.enforce_ds_max
                    app.enforce_ds_maxDropDown.Value = 'true';
                else
                    app.enforce_ds_maxDropDown.Value = 'false';
                end
                app.bifurcationDropDown.Value = 'on'; % different from default
                app.n_bif_searchEditField.Value = num2str(app.Opt.n_bif_search);
                if app.Opt.bif_rand_dir
                    app.bif_rand_dirDropDown.Value = 'true';
                else
                    app.bif_rand_dirDropDown.Value = 'false';
                end
                app.plotDropDown.Value = 'on'; % different from default
                if app.Opt.plot_pause
                    app.plot_pauseEditField.Value = 'true';
                else
                    app.plot_pauseEditField.Value = 'false';
                end
                app.live_plot_figEditField.Value = num2str(app.Opt.live_plot_fig);
                app.plot_var_of_interestEditField.Value = num2str(app.Opt.plot_var_of_interest);
                app.plot_vars_indexEditField_2.Value = app.Opt.plot_vars_index;
                if app.Opt.display
                    app.displayDropDown.Value = 'true';
                else
                    app.displayDropDown.Value = 'false';
                end
                stru = app.Opt.predictor;
                fi = fields(stru);
                val = 'off';
                for k=1:length(fi)
                    if getfield(stru, fi{k})
                        val = fi{k};
                    end
                end
                app.predictorDropDown.Value = val;
                if app.Opt.predictor_polynomial_adaptive
                    app.predictor_polynomial_adaptiveDropDown.Value = 'true';
                else
                    app.predictor_polynomial_adaptiveDropDown.Value = 'false';
                end
                if app.Opt.predictor_solver
                    app.predictor_solverDropDown.Value = 'true';
                else
                    app.predictor_solverDropDown.Value = 'false';
                end
                app.predictor_polynomial_fitEditField.Value = num2str(app.Opt.predictor_polynomial_fit);
                app.predictor_polynomial_orderEditField.Value = num2str(app.Opt.predictor_polynomial_order);
                app.l_0EditField.Value = 'lams';
                app.l_startEditField.Value = 'lams';
                app.l_endEditField.Value = 'lame';
                app.l_targetEditField.Value = 'lame';
                app.max_error_counterEditField.Value = num2str(app.Opt.max_error_counter);
                app.n_iter_optEditField.Value = num2str(app.Opt.n_iter_opt);
                app.n_step_maxEditField.Value = num2str(app.Opt.n_step_max);
                app.stepback_error_counterEditField.Value = num2str(app.Opt.stepback_error_counter);
                stru = app.Opt.solver;
                fi = fields(stru);
                val = 'off';
                for k=1:length(fi)
                    if getfield(stru, fi{k})
                        val = fi{k};
                    end
                end
                app.solverDropDown.Value = val;
                if app.Opt.solver_force1it
                    app.solver_force1itDropDown.Value = 'true';
                else
                    app.solver_force1itDropDown.Value = 'false';
                end
                app.solver_tolEditField.Value = num2str(app.Opt.solver_tol);
                app.startstepsizeEditField.Value = 'ds0';
                if app.Opt.stop_on_bifurcation
                    app.stop_on_bifurcationDropDown.Value = 'true';
                else
                    app.stop_on_bifurcationDropDown.Value = 'false';
                end
                app.v_0EditField.Value = 'v0';
                app.Function_nameEditField.Value = 'testfun02';
                if app.Opt.check_residual
                    app.check_residualDropDown.Value = 'true';
                else
                    app.check_residualDropDown.Value = 'false';
                end
                if app.Opt.correct_predictor
                    app.correct_predictorDropDown.Value = 'true';
                else
                    app.correct_predictorDropDown.Value = 'false';
                end
                if islogical(app.Opt.approve_manually)
                    if app.Opt.approve_manually
                        app.approve_manuallyEditField.Value = 'true';
                    else
                        app.approve_manuallyEditField.Value = 'false';
                    end
                else
                    app.approve_manuallyEditField.Value = num2str(app.Opt.approve_manually);
                end
                %
            end
            if part == 0 || part == 2
                %% advanced options
                %
                stru = app.Opt.homotopy;
                fi = fields(stru);
                val = 'off';
                for k=1:length(fi)
                    if getfield(stru, fi{k})
                        val = fi{k};
                    end
                end
                app.homotopyDropDown.Value = val;
                app.homotopy_error_counterEditField.Value = num2str(app.Opt.homotopy_error_counter);
                if app.Opt.deflation
                    app.deflationDropDown.Value = 'true';
                else
                    app.deflationDropDown.Value = 'false';
                end
                app.deflation_error_counterEditField.Value = num2str(app.Opt.deflation_error_counter);
                stru = app.Opt.diffquot;
                fi = fields(stru);
                val = 'off';
                for k=1:length(fi)
                    if getfield(stru, fi{k})
                        val = fi{k};
                    end
                end
                app.diffquotDropDown.Value = val;
                app.alpha_reverseEditField.Value = num2str(app.Opt.alpha_reverse);
                if app.Opt.include_reverse
                    app.include_reverseDropDown.Value = 'true';
                else
                    app.include_reverseDropDown.Value = 'false';
                end
                stru = app.Opt.reverse;
                fi = fields(stru);
                val = 'off';
                for k=1:length(fi)
                    if getfield(stru, fi{k})
                        val = fi{k};
                    end
                end
                app.reverseDropDown.Value = val;
                stru = app.Opt.scaling;
                fi = fields(stru);
                val = 'off';
                for k=1:length(fi)
                    if getfield(stru, fi{k})
                        val = fi{k};
                    end
                end
                app.scalingDropDown.Value = val;
                app.dscale0EditField.Value = app.Opt.dscale0;
                app.dscale_minEditField.Value = num2str(app.Opt.dscale_min);
                app.break_functionEditField.Value = char(app.Opt.break_function);
                app.directionEditField.Value = app.Opt.direction;
                app.closed_counterEditField.Value = num2str(app.Opt.closed_counter);
                if app.Opt.closed_curve_detection
                    app.closed_curve_detectionDropDown.Value = 'true';
                else
                    app.closed_curve_detectionDropDown.Value = 'false';
                end
                %
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                %% adjust window and elements
                %
                %Give resolution for your previous screen:
                old_screen=[1920,1080];
                %Collect information about new screen:
                set(0,'units','pixels');
                Pix_SS = get(0,'screensize');
                %Assign coefficient of reduction/increase:
                k_hor=Pix_SS(3)/old_screen(1);
                k_vert=Pix_SS(4)/old_screen(2);
                %Get size of your original application UI:
                uisize = app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Position;
                app.uisize0 = uisize;
                screenWidth = uisize(3);
                screenHeight = uisize(4);
                left = uisize(1);
                bottom = uisize(2);
                %Assign new size for the width and height:
                width = screenWidth*k_hor;
                height = screenHeight*k_vert;
                %Assign coefficient to reduce font sizes:
                k_font=(k_hor+k_vert)/2;
                drawnow;
                %Collect UI elements:
                comp=app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Children;
                %Change UI element size to new scaled size:
                app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Position = [left bottom width height];
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    if numel(comp(i).Position) == 4
                        app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                        app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                        app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                        app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                        %Catch an error for possible UI elements not having FontSize
                            try
                            a{i} = comp(i).FontSize;
                            catch
                            warning('This object does not have font option.  Assigning a value of 0.');
                            a{i} = 0;
                            end
                            %CHange FontSize of all elements that actually have
                            %FontSize component
                        if a{i}~=0
                        app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Children(i).FontSize=comp(i).FontSize*k_font;
                        end
                    end
                end
                % adjust plot offset
                app.pos_Offset = [820*k_hor,245*k_vert];
                app.pos_plot = [968*k_hor, 480*k_vert];
                %
                %% clear cmd window and variables
                clc;
                vars = {'corrector_method','stepsizecontrolmethod','step_size_alpha_max',...
                'step_size_angle','step_e_max','maxstepsize','minstepsize',...
                'bifurcation_method','n_bif_search','bif_rand_dir','plot_method',...
                'plot_pause','live_plot_fig','plot_var_of_interest','plot_vars_index',...
                'ShowDisplay','predictor_method','predictor_polynomial_adaptive','predictor_solver',...
                'predictor_polynomial_fit','predictor_polynomial_order','homotopy_method','homotopyerrorcounter',...
                'deflation','deflationerrorcounter','l_start','l_start',...
                'maxerrorcounter','n_iter_opt','n_step_max','stepback_error_counter',...
                'diffquot','alphareverse','includereverse','reverse_method',...
                'scaling_method','dscale0','dscale_min','solver_method',...
                'Forcesolver1it','solvertolerance','breakfunction','direction',...
                'closed_counter','closedcurvedetection','ds0','stop_on_bifurcation',...
                'v0'};
                clear(vars{:});
                %
                % find folder
                %
                if(~isdeployed)
                    cd(fileparts(which('Continuation.mlapp')))
                    cd('..\');    
                end
                addpath('..\src');
                addpath('..\settings')
                app.Opt = csf2struct('Opt');
                %
                % start showing command window
                %
                try
                    jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                    jCmdWin = jDesktop.getClient('Command Window');
                    jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                    set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
                catch
                    warndlg('fatal error');
                end
                %
                % set all options to default
                getDefaultValues(app);
                %
        end

        % Button pushed function: ResetSettingsButton
        function ResetSettingsButtonPushed(app, event)
            getDefaultValues(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % clear variables
            %
            vars = {'l_0','var0','l_end','ds0','corrector_method',...
                'stepsizecontrolmethod','step_size_alpha_max','step_size_angle','step_e_max','maxstepsize',...
                'minstepsize','bifurcation_method','n_bif_search','bif_rand_dir','plot_method',...
                'plot_pause','live_plot_fig','plot_var_of_interest','plot_vars_index','ShowDisplay',...
                'predictor_method','predictor_polynomial_adaptive','predictor_solver','predictor_polynomial_fit','predictor_polynomial_order',...
                'homotopy_method','homotopyerrorcounter','deflation','deflationerrorcounter','l_start',...
                'l_target','maxerrorcounter','n_iter_opt','n_step_max','stepback_error_counter',...
                'diffquot','alphareverse','includereverse','reverse_method','scaling_method',...
                'dscale0','dscale_min','solver_method','Forcesolver1it','solvertolerance',...
                'breakfunction','direction','closed_counter','closedcurvedetection','stop_on_bifurcation',...
                'check_residual','correct_predictor','approve_manually'};
            clear(vars{:});
            %
            % add function
            %
            if strcmp(app.ImplementfunctionbyDropDown.Value, 'Path')
                fun_path = app.Function_pathEditField.Value;
                addpath(fun_path);
                eval(app.Function_nameEditField.Value);
            else
                function_code_lines = app.TextArea.Value;
                for k_lines = 1:length(function_code_lines)
                    eval(function_code_lines{k_lines});
                end
            end
            %
            % get input values
            %
            l_0 = eval(app.l_0EditField.Value);
            var0 = eval(app.v_0EditField.Value);
            l_end = eval(app.l_endEditField.Value);
            ds0 = eval(app.startstepsizeEditField.Value);
            
            corrector_method = app.correctorDropDown.Value;
            stepsizecontrolmethod = app.step_size_controlDropDown.Value;
            step_size_alpha_max = eval(app.step_size_alpha_maxEditField.Value);
            step_size_angle = eval(app.step_size_angleEditField.Value);
            step_e_max = eval(app.step_e_maxEditField.Value);
            maxstepsize = eval(app.ds_maxEditField.Value);
            minstepsize = eval(app.ds_minEditField.Value);
            bifurcation_method = app.bifurcationDropDown.Value;
            n_bif_search = eval(app.n_bif_searchEditField.Value);
            bif_rand_dir = eval(app.bif_rand_dirDropDown.Value);
            plot_method = app.plotDropDown.Value;
            plot_pause = eval(app.plot_pauseEditField.Value);
            live_plot_fig = eval(app.live_plot_figEditField.Value);
            plot_var_of_interest = eval(app.plot_var_of_interestEditField.Value);
            plot_vars_index = eval(app.plot_vars_indexEditField_2.Value);
            ShowDisplay = eval(app.displayDropDown.Value);
            predictor_method = app.predictorDropDown.Value;
            predictor_polynomial_adaptive = eval(app.predictor_polynomial_adaptiveDropDown.Value);
            predictor_solver = eval(app.predictor_solverDropDown.Value);
            predictor_polynomial_fit = eval(app.predictor_polynomial_fitEditField.Value);
            predictor_polynomial_order = eval(app.predictor_polynomial_orderEditField.Value);
            homotopy_method = app.homotopyDropDown.Value;
            homotopyerrorcounter = eval(app.homotopy_error_counterEditField.Value);
            deflation = eval(app.deflationDropDown.Value);
            deflationerrorcounter = eval(app.deflation_error_counterEditField.Value);
            l_start = eval(app.l_startEditField.Value);
            l_target = eval(app.l_targetEditField.Value);
            maxerrorcounter = eval(app.max_error_counterEditField.Value);
            n_iter_opt = eval(app.n_iter_optEditField.Value);
            n_step_max = eval(app.n_step_maxEditField.Value);
            stepback_error_counter = eval(app.stepback_error_counterEditField.Value);
            diffquot = app.diffquotDropDown.Value;
            alphareverse = eval(app.alpha_reverseEditField.Value);
            includereverse = eval(app.include_reverseDropDown.Value);
            reverse_method = app.reverseDropDown.Value;
            scaling_method = app.scalingDropDown.Value;
            dscale0 = eval(app.dscale0EditField.Value);
            dscale_min = eval(app.dscale_minEditField.Value);
            solver_method = app.solverDropDown.Value;
            Forcesolver1it = eval(app.solver_force1itDropDown.Value);
            solvertolerance = eval(app.solver_tolEditField.Value);
            breakfunction = eval(app.break_functionEditField.Value);
            direction = eval(app.directionEditField.Value);
            closed_counter = eval(app.closed_counterEditField.Value);
            closedcurvedetection = eval(app.closed_curve_detectionDropDown.Value);
            stop_on_bifurcation = eval(app.stop_on_bifurcationDropDown.Value);
            check_residual = eval(app.check_residualDropDown.Value);
            correct_predictor = eval(app.correct_predictorDropDown.Value);
            approve_manually = eval(app.approve_manuallyEditField.Value);
            enforce_ds_max = eval(app.enforce_ds_maxDropDown.Value);
            %
            % create figure
            %
            if isnan(live_plot_fig)
                close all;
                fig = figure(1);
                fig.Tag = 'app_fig';
                live_plot_fig = fig.Number;
                app.fig_num = live_plot_fig;
                fig.Position = [app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Position(1:2) + app.pos_Offset, app.pos_plot];
                app.EnabletoolbarinplotCheckBox.Value = true;
                app.EnablemenubarinplotCheckBox.Value = true;
            else
                fig = figure(live_plot_fig);
                fig.Tag = 'app_fig';
                app.fig_num = live_plot_fig;
                fig.Position = [app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Position(1:2) + app.pos_Offset, app.pos_plot];
                app.EnabletoolbarinplotCheckBox.Value = true;
                app.EnablemenubarinplotCheckBox.Value = true;
            end
            %
            % start continuation
            %
            continuation(fun,var0,l_start,l_end,ds0,...
                 'alpha_reverse',alphareverse,...
                 'approve_manually',approve_manually,...
                 'bifurcation',bifurcation_method,...
                 'bif_rand_dir', bif_rand_dir,...
                 'break_function',breakfunction,...
                 'check_residual',check_residual,...
                 'closed_counter', closed_counter,...
                 'closed_curve_detection',closedcurvedetection,...
                 'corrector',corrector_method,...
                 'correct_predictor',correct_predictor,...
                 'deflation',deflation,...
                 'deflation_error_counter',deflationerrorcounter,...
                 'diffquot',diffquot,...
                 'direction',direction,...
                 'display',ShowDisplay,...
                 'ds_max',maxstepsize,...
                 'ds_min',minstepsize,...
                 'dscale0',dscale0,...
                 'dscale_min', dscale_min,...
                 'enforce_ds_max',enforce_ds_max,...
                 'homotopy',homotopy_method,...
                 'homotopy_error_counter',homotopyerrorcounter,...
                 'include_reverse',includereverse,...
                 'l_0',l_0,...
                 'l_target',l_target,...
                 'live_plot_fig', live_plot_fig,...
                 'max_error_counter',maxerrorcounter,...
                 'n_bif_search',n_bif_search,...
                 'n_iter_opt',n_iter_opt,...
                 'n_step_max',n_step_max,...
                 'plot',plot_method,...
                 'plot_pause',plot_pause,...
                 'plot_var_of_interest', plot_var_of_interest,...
                 'plot_vars_index',plot_vars_index,...                
                 'predictor',predictor_method,...
                 'predictor_polynomial_adaptive',predictor_polynomial_adaptive,...
                 'predictor_polynomial_fit',predictor_polynomial_fit,...
                 'predictor_polynomial_order',predictor_polynomial_order,...
                 'predictor_solver',predictor_solver,...
                 'reverse',reverse_method,...
                 'scaling',scaling_method,...
                 'solver',solver_method,...
                 'solver_force1it',Forcesolver1it,...
                 'solver_tol',solvertolerance,...
                 'stepback_error_counter',stepback_error_counter,...
                 'step_size_alpha_max',step_size_alpha_max,...
                 'step_size_angle',step_size_angle,...
                 'step_size_control',stepsizecontrolmethod,...
                 'step_size_e_max',step_e_max,...
                 'stop_on_bifurcation',stop_on_bifurcation);
        end

        % Callback function
        function CloseallFiguresButtonPushed(app, event)
            close all;
        end

        % Value changed function: ImplementfunctionbyDropDown
        function ImplementfunctionbyDropDownValueChanged(app, event)
            value = app.ImplementfunctionbyDropDown.Value;
            if strcmp(value,'Path')
                app.Function_pathEditField.Visible = true;
                app.Function_pathEditFieldLabel.Visible = true;
                app.Function_nameEditField.Visible = true;
                app.Function_nameEditFieldLabel.Visible = true;
                app.TextArea.Visible = false;
            else
                app.Function_pathEditField.Visible = false;
                app.Function_pathEditFieldLabel.Visible = false;
                app.Function_nameEditField.Visible = false;
                app.Function_nameEditFieldLabel.Visible = false;
                app.TextArea.Visible = true;
            end
        end

        % Close request function: 
        % continuationtoolInstituteofDynamicsandVibrationResearchUIFigure
        function appCloseRequest(app, event)
            close all;
            delete(app)
        end

        % Value changed function: EnablemenubarinplotCheckBox
        function EnablemenubarinplotCheckBoxValueChanged(app, event)
            value = app.EnablemenubarinplotCheckBox.Value;
            if ishandle(app.fig_num)
                fig = figure(app.fig_num);
                if value
                    set(fig, 'MenuBar', 'figure');
                else
                    set(fig, 'MenuBar', 'none');
                end
            end
        end

        % Value changed function: EnabletoolbarinplotCheckBox
        function EnabletoolbarinplotCheckBoxValueChanged(app, event)
            value = app.EnabletoolbarinplotCheckBox.Value;
            if ishandle(app.fig_num)
                fig = figure(app.fig_num);
                if value
                    set(fig, 'ToolBar', 'figure');
                else
                    set(fig, 'ToolBar', 'none');
                end
            end
        end

        % Value changed function: step_size_controlDropDown
        function step_size_controlDropDownValueChanged(app, event)
            value = app.step_size_controlDropDown.Value;
            if strcmp(value,'angle')
                app.step_size_alpha_maxEditFieldLabel.Visible = false;
                app.step_size_alpha_maxEditField.Visible = false;
                app.step_e_maxEditFieldLabel.Visible = false;
                app.step_e_maxEditField.Visible = false;
                app.step_size_angleEditFieldLabel.Visible = true;
                app.step_size_angleEditField.Visible = true;
            elseif strcmp(value,'curvature')
                app.step_size_alpha_maxEditFieldLabel.Visible = true;
                app.step_size_alpha_maxEditField.Visible = true;
                app.step_e_maxEditFieldLabel.Visible = false;
                app.step_e_maxEditField.Visible = false;
                app.step_size_angleEditFieldLabel.Visible = false;
                app.step_size_angleEditField.Visible = false;
            elseif strcmp(value,'pid')
                app.step_size_alpha_maxEditFieldLabel.Visible = false;
                app.step_size_alpha_maxEditField.Visible = false;
                app.step_e_maxEditFieldLabel.Visible = true;
                app.step_e_maxEditField.Visible = true;
                app.step_size_angleEditFieldLabel.Visible = false;
                app.step_size_angleEditField.Visible = false;
            else
                app.step_size_alpha_maxEditFieldLabel.Visible = false;
                app.step_size_alpha_maxEditField.Visible = false;
                app.step_e_maxEditFieldLabel.Visible = false;
                app.step_e_maxEditField.Visible = false;
                app.step_size_angleEditFieldLabel.Visible = false;
                app.step_size_angleEditField.Visible = false;
            end
        end

        % Value changed function: plotDropDown
        function plotDropDownValueChanged(app, event)
            value = app.plotDropDown.Value;
            if strcmp(value,'off')
                app.plot_pauseEditField.Visible = false;
                app.plot_pauseEditFieldLabel.Visible = false;
                app.plot_var_of_interestEditField.Visible = false;
                app.plot_var_of_interestEditFieldLabel.Visible = false;
                app.plot_vars_indexEditField_2.Visible = false;
                app.plot_vars_indexEditField_2Label.Visible = false;
                app.live_plot_figEditField.Visible = false;
                app.live_plot_figEditFieldLabel.Visible = false;
            else
                app.plot_pauseEditField.Visible = true;
                app.plot_pauseEditFieldLabel.Visible = true;
                app.plot_var_of_interestEditField.Visible = true;
                app.plot_var_of_interestEditFieldLabel.Visible = true;
                app.plot_vars_indexEditField_2.Visible = true;
                app.plot_vars_indexEditField_2Label.Visible = true;
                app.live_plot_figEditField.Visible = true;
                app.live_plot_figEditFieldLabel.Visible = true;
            end
        end

        % Value changed function: bifurcationDropDown
        function bifurcationDropDownValueChanged(app, event)
            value = app.bifurcationDropDown.Value;
            if strcmp(value, 'on') || strcmp(value, 'mark') || strcmp(value, 'determine')
                app.stop_on_bifurcationDropDownLabel.Visible = true;
                app.stop_on_bifurcationDropDown.Visible = true;
                app.n_bif_searchEditFieldLabel.Visible = false;
                app.n_bif_searchEditField.Visible = false;
                app.bif_rand_dirDropDownLabel.Visible = false;
                app.bif_rand_dirDropDown.Visible = false;
            elseif strcmp(value, 'trace')
                app.stop_on_bifurcationDropDownLabel.Visible = true;
                app.stop_on_bifurcationDropDown.Visible = true;
                app.n_bif_searchEditFieldLabel.Visible = true;
                app.n_bif_searchEditField.Visible = true;
                app.bif_rand_dirDropDownLabel.Visible = true;
                app.bif_rand_dirDropDown.Visible = true;
            else
                app.stop_on_bifurcationDropDownLabel.Visible = false;
                app.stop_on_bifurcationDropDown.Visible = false;
                app.n_bif_searchEditFieldLabel.Visible = false;
                app.n_bif_searchEditField.Visible = false;
                app.bif_rand_dirDropDownLabel.Visible = false;
                app.bif_rand_dirDropDown.Visible = false;
            end
        end

        % Value changed function: scalingDropDown
        function scalingDropDownValueChanged(app, event)
            value = app.scalingDropDown.Value;
            if strcmp(value, 'off')
                app.dscale0EditFieldLabel.Visible = false;
                app.dscale0EditField.Visible = false;
                app.dscale_minEditFieldLabel.Visible = false;
                app.dscale_minEditField.Visible = false;
            else
                app.dscale0EditFieldLabel.Visible = true;
                app.dscale0EditField.Visible = true;
                app.dscale_minEditFieldLabel.Visible = true;
                app.dscale_minEditField.Visible = true;
            end
        end

        % Value changed function: closed_curve_detectionDropDown
        function closed_curve_detectionDropDownValueChanged(app, event)
            value = app.closed_curve_detectionDropDown.Value;
            if strcmp(value, 'true')
                app.closed_counterEditField.Visible = true;
                app.closed_counterEditFieldLabel.Visible = true;
            else
                app.closed_counterEditField.Visible = false;
                app.closed_counterEditFieldLabel.Visible = false;
            end
        end

        % Value changed function: homotopyDropDown
        function homotopyDropDownValueChanged(app, event)
            value = app.homotopyDropDown.Value;
            if strcmp(value, 'off')
                app.homotopy_error_counterEditFieldLabel.Visible = false;
                app.homotopy_error_counterEditField.Visible = false;
            else
                app.homotopy_error_counterEditFieldLabel.Visible = true;
                app.homotopy_error_counterEditField.Visible = true;
            end
        end

        % Value changed function: deflationDropDown
        function deflationDropDownValueChanged(app, event)
            value = app.deflationDropDown.Value;
            if strcmp(value, 'false')
                app.deflation_error_counterEditFieldLabel.Visible = false;
                app.deflation_error_counterEditField.Visible = false;
            else
                app.deflation_error_counterEditFieldLabel.Visible = true;
                app.deflation_error_counterEditField.Visible = true;
            end
        end

        % Menu selected function: OpencontinuationfunctionMenu
        function OpencontinuationfunctionMenuSelected(app, event)
            open continuation.m;
        end

        % Menu selected function: OpensrcfolderMenu
        function OpensrcfolderMenuSelected(app, event)
            winopen('..\src\');
        end

        % Menu selected function: WatchdocumentationMenu_2
        function WatchdocumentationMenu_2Selected(app, event)
            addpath('..\doc\html\');
            web('continuation.html');
        end

        % Menu selected function: WatchGitHubprojectMenu_2
        function WatchGitHubprojectMenu_2Selected(app, event)
            web('https://github.com/foersterIDS/Continuation','-new','-notoolbar');
        end

        % Menu selected function: ContactUsMenu
        function ContactUsMenuSelected(app, event)
            email = 'foerster@ids.uni-hannover.de';
            url = ['mailto:',email];
            web(url);
            disp(['Contact us via ',email]);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            url = 'https://www.ids.uni-hannover.de/';
            web(url);
        end

        % Value changed function: ShowadvancedoptionsCheckBox
        function ShowadvancedoptionsCheckBoxValueChanged2(app, event)
            value = app.ShowadvancedoptionsCheckBox.Value;
            if value
                app.TabGroup2.Visible = true;
                app.advancedoptionsLabel.BackgroundColor = [0.06,0.11,1.00];
                app.ResetButton_3.BackgroundColor = [0.21,0.18,1.00];
            else
                app.TabGroup2.Visible = false;
                app.advancedoptionsLabel.BackgroundColor = [0.43,0.4,1.00];
                app.ResetButton_3.BackgroundColor = [0.35,0.32,1.00];
            end
        end

        % Button pushed function: GetfigureButton
        function GetfigureButtonPushed(app, event)
            if ishandle(app.fig_num)
                fig = figure(app.fig_num);
                fig.Position = [app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Position(1:2) + app.pos_Offset, app.pos_plot];
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ImplementfunctionbyDropDown.Value = 'Path';
            app.Function_pathEditField.Value = 'test_cases';
            app.Function_nameEditField.Value = 'testfun02';
            app.ImplementfunctionbyDropDownValueChanged(app);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            getDefaultValues(app,1)
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            getDefaultValues(app,2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure = uifigure('Visible', 'off');
            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Name = 'continuation tool - Institute of Dynamics and Vibration Research';
            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Icon = 'icon-1.png';
            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.CloseRequestFcn = createCallbackFcn(app, @appCloseRequest, true);
            app.WatchdocumentationMenu_2 = uimenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.WatchdocumentationMenu_2.MenuSelectedFcn = createCallbackFcn(app, @WatchdocumentationMenu_2Selected, true);
            app.WatchdocumentationMenu_2.Text = 'Watch documentation';
            app.WatchGitHubprojectMenu_2 = uimenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.WatchGitHubprojectMenu_2.MenuSelectedFcn = createCallbackFcn(app, @WatchGitHubprojectMenu_2Selected, true);
            app.WatchGitHubprojectMenu_2.Text = 'Watch GitHub project';
            app.OpencontinuationfunctionMenu = uimenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.OpencontinuationfunctionMenu.MenuSelectedFcn = createCallbackFcn(app, @OpencontinuationfunctionMenuSelected, true);
            app.OpencontinuationfunctionMenu.Text = 'Open continuation function';
            app.OpensrcfolderMenu = uimenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.OpensrcfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpensrcfolderMenuSelected, true);
            app.OpensrcfolderMenu.Text = 'Open src folder';
            app.ContactUsMenu = uimenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.ContactUsMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactUsMenuSelected, true);
            app.ContactUsMenu.Text = 'Contact Us';
            app.Label_4 = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Label_3.Text = '';
            app.IDSTidoKubatschek22092021Label = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.IDSTidoKubatschek22092021Label.Text = '© IDS  -  Tido Kubatschek (22.09.2021)  ';
            app.Label_2 = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Label_2.Text = '';
            app.TabGroup3 = uitabgroup(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.GeneralTab = uitab(app.TabGroup3);
            app.GeneralTab.Title = 'General';
            app.TextArea_3 = uitextarea(app.GeneralTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Use the below fields to start a path continuation.'; ''; 'You may either use the path of a .m-file with your function or implement the function as code. Hover over a field to get information. Click on ''Run'' to start continuation. Press ''Reset Settings'' to reset fields to default.'; ''; 'Click on ''Get Figure'' to move figure to default position.'};
            app.NeedHelpTab = uitab(app.TabGroup3);
            app.NeedHelpTab.Title = 'Need Help?';
            app.TextArea_4 = uitextarea(app.NeedHelpTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Help...................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................................'};
            app.Label = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Label.Text = '';
            app.Function_pathEditFieldLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Function_pathEditFieldLabel.Tooltip = {'Enter the path of your function'};
            app.Function_pathEditFieldLabel.Text = 'Function_path';
            app.Function_pathEditField = uieditfield(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'text');
            app.Function_pathEditField.Placeholder = 'Enter path of your function';
            app.Function_pathEditField.Value = 'test_cases';
            app.StartButton = uibutton(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'arrow.png';
            app.StartButton.Tooltip = {'click to start continuation'};
            app.StartButton.Text = 'Start   ';
            app.ResetSettingsButton = uibutton(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'push');
            app.ResetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSettingsButtonPushed, true);
            app.ResetSettingsButton.Icon = '169903.png';
            app.ResetSettingsButton.Tooltip = {'click to reset all settings to default'};
            app.ResetSettingsButton.Text = 'Reset Settings ';
            app.basicoptionsLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.basicoptionsLabel.Text = 'basic options';
            app.TabGroup = uitabgroup(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.lambdaandloopspecificationsTab = uitab(app.TabGroup);
            app.lambdaandloopspecificationsTab.Title = 'lambda and loop specifications';
            app.l_0EditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.l_0EditFieldLabel.Tooltip = {'set start value different from l_start'};
            app.l_0EditFieldLabel.Text = 'l_0';
            app.l_0EditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.l_0EditField.Tooltip = {'set start value different from l_start'};
            app.l_0EditField.Value = 'lams';
            app.l_targetEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.l_targetEditFieldLabel.Tooltip = {'set end value different from l_end'};
            app.l_targetEditFieldLabel.Text = 'l_target';
            app.l_targetEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.l_targetEditField.Tooltip = {'set end value different from l_end'};
            app.l_targetEditField.Value = 'lame';
            app.v_0EditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.v_0EditFieldLabel.Tooltip = {'Starting values for variables'};
            app.v_0EditFieldLabel.Text = 'v_0';
            app.v_0EditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.v_0EditField.Tooltip = {'Starting values for variables'};
            app.v_0EditField.Value = 'v0';
            app.l_startEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.l_startEditFieldLabel.Tooltip = {'starting lambda of function'};
            app.l_startEditFieldLabel.Text = 'l_start';
            app.l_startEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.l_startEditField.Tooltip = {'starting lambda of function'};
            app.l_startEditField.Value = 'lams';
            app.l_endEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.l_endEditFieldLabel.Tooltip = {'end lambda of function'};
            app.l_endEditFieldLabel.Text = 'l_end';
            app.l_endEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.l_endEditField.Tooltip = {'end lambda of function'};
            app.l_endEditField.Value = 'lame';
            app.max_error_counterEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.max_error_counterEditFieldLabel.Text = 'max_error_counter';
            app.max_error_counterEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.max_error_counterEditField.Tooltip = {'maximum number of errors'};
            app.max_error_counterEditField.Value = '20';
            app.n_iter_optEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.n_iter_optEditFieldLabel.Text = 'n_iter_opt';
            app.n_iter_optEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.n_iter_optEditField.Tooltip = {'set optimal number of iterations per step'};
            app.n_iter_optEditField.Value = '3';
            app.n_step_maxEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.n_step_maxEditFieldLabel.Text = 'n_step_max';
            app.n_step_maxEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.n_step_maxEditField.Tooltip = {'max number of loop runs'};
            app.n_step_maxEditField.Value = 'Inf';
            app.stepback_error_counterEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.stepback_error_counterEditFieldLabel.Text = 'stepback_error_counter';
            app.stepback_error_counterEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.stepback_error_counterEditField.Tooltip = {'max number of errors before step back'};
            app.stepback_error_counterEditField.Value = '3';
            app.check_residualDropDownLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.check_residualDropDownLabel.Text = 'check_residual';
            app.check_residualDropDown = uidropdown(app.lambdaandloopspecificationsTab);
            app.check_residualDropDown.Items = {'false', 'true'};
            app.check_residualDropDown.Tooltip = {'result is checked no matter the solver tolerance is met or not.'};
            app.check_residualDropDown.Value = 'false';
            app.approve_manuallyEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.approve_manuallyEditFieldLabel.Text = 'approve_manually';
            app.approve_manuallyEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.approve_manuallyEditField.Tooltip = {'results can be approved manually by user. possible values: true | false | scalar,double'};
            app.approve_manuallyEditField.Value = 'false';
            app.CorrectorandstepsizemethodTab = uitab(app.TabGroup);
            app.CorrectorandstepsizemethodTab.Title = 'Corrector and stepsize method';
            app.correctorDropDownLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.correctorDropDownLabel.Tooltip = {'Choose method for corrector'};
            app.correctorDropDownLabel.Text = 'corrector';
            app.correctorDropDown = uidropdown(app.CorrectorandstepsizemethodTab);
            app.correctorDropDown.Items = {'sphere', 'linear', 'ellipsoid', 'ellipsoid2', 'unique'};
            app.correctorDropDown.Tooltip = {'Choose method for corrector'};
            app.correctorDropDown.Value = 'sphere';
            app.step_size_controlDropDownLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.step_size_controlDropDownLabel.Text = 'step_size_control';
            app.step_size_controlDropDown = uidropdown(app.CorrectorandstepsizemethodTab);
            app.step_size_controlDropDown.Items = {'iterations', 'angle', 'curvature', 'pid'};
            app.step_size_controlDropDown.ValueChangedFcn = createCallbackFcn(app, @step_size_controlDropDownValueChanged, true);
            app.step_size_controlDropDown.Tooltip = {'Choose method for step size control'};
            app.step_size_controlDropDown.Value = 'iterations';
            app.ds_maxEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.ds_maxEditFieldLabel.Tooltip = {'maximum stepsize. Input can either be numerical or corresponding variable'};
            app.ds_maxEditFieldLabel.Text = 'ds_max';
            app.ds_maxEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'text');
            app.ds_maxEditField.Tooltip = {'maximum stepsize. Input can either be numerical or corresponding variable'};
            app.ds_maxEditField.Value = 'inf';
            app.ds_minEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.ds_minEditFieldLabel.Tooltip = {'minimum stepsize. Input can either be numerical or corresponding variable'};
            app.ds_minEditFieldLabel.Text = 'ds_min';
            app.ds_minEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'text');
            app.ds_minEditField.Tooltip = {'minimum stepsize. Input can either be numerical or corresponding variable'};
            app.ds_minEditField.Value = '0';
            app.startstepsizeEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.startstepsizeEditFieldLabel.Tooltip = {'step size to start with'};
            app.startstepsizeEditFieldLabel.Text = 'start stepsize';
            app.startstepsizeEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'text');
            app.startstepsizeEditField.Tooltip = {'step size to start with'};
            app.startstepsizeEditField.Value = 'ds0';
            app.step_e_maxEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.step_e_maxEditFieldLabel.Visible = 'off';
            app.step_e_maxEditFieldLabel.Text = 'step_e_max';
            app.step_e_maxEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'text');
            app.step_e_maxEditField.Visible = 'off';
            app.step_e_maxEditField.Tooltip = {'defines weight for step_size_control method pid'};
            app.step_e_maxEditField.Value = '0.3';
            app.step_size_alpha_maxEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.step_size_alpha_maxEditFieldLabel.Visible = 'off';
            app.step_size_alpha_maxEditFieldLabel.Text = 'step_size_alpha_max';
            app.step_size_alpha_maxEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'text');
            app.step_size_alpha_maxEditField.Visible = 'off';
            app.step_size_alpha_maxEditField.Tooltip = {'defines max. local curvature for step_size_control method curvature'};
            app.step_size_alpha_maxEditField.Value = '0.45';
            app.step_size_angleEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.step_size_angleEditFieldLabel.Visible = 'off';
            app.step_size_angleEditFieldLabel.Text = 'step_size_angle';
            app.step_size_angleEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'text');
            app.step_size_angleEditField.Visible = 'off';
            app.step_size_angleEditField.Tooltip = {'defines max. angle for  step_size_control method angle'};
            app.step_size_angleEditField.Value = '(5/360)*(2*pi)';
            app.enforce_ds_maxDropDownLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.enforce_ds_maxDropDownLabel.Text = 'enforce_ds_max';
            app.enforce_ds_maxDropDown = uidropdown(app.CorrectorandstepsizemethodTab);
            app.enforce_ds_maxDropDown.Items = {'true', 'false'};
            app.enforce_ds_maxDropDown.Value = 'false';
            app.predictorTab = uitab(app.TabGroup);
            app.predictorTab.Title = 'predictor';
            app.predictorDropDownLabel = uilabel(app.predictorTab);
            app.predictorDropDownLabel.Text = 'predictor';
            app.predictorDropDown = uidropdown(app.predictorTab);
            app.predictorDropDown.Items = {'polynomial', 'tangential'};
            app.predictorDropDown.Tooltip = {'Determine used predictor'};
            app.predictorDropDown.Value = 'polynomial';
            app.predictor_polynomial_adaptiveDropDownLabel = uilabel(app.predictorTab);
            app.predictor_polynomial_adaptiveDropDownLabel.Text = 'predictor_polynomial_adaptive';
            app.predictor_polynomial_adaptiveDropDown = uidropdown(app.predictorTab);
            app.predictor_polynomial_adaptiveDropDown.Items = {'false', 'true'};
            app.predictor_polynomial_adaptiveDropDown.Tooltip = {'Calculate polynomial fit and polynomial order adaptivly'};
            app.predictor_polynomial_adaptiveDropDown.Value = 'false';
            app.predictor_solverDropDownLabel = uilabel(app.predictorTab);
            app.predictor_solverDropDownLabel.Text = 'predictor_solver';
            app.predictor_solverDropDown = uidropdown(app.predictorTab);
            app.predictor_solverDropDown.Items = {'false', 'true'};
            app.predictor_solverDropDown.Value = 'false';
            app.predictor_polynomial_fitEditFieldLabel = uilabel(app.predictorTab);
            app.predictor_polynomial_fitEditFieldLabel.Text = 'predictor_polynomial_fit';
            app.predictor_polynomial_fitEditField = uieditfield(app.predictorTab, 'text');
            app.predictor_polynomial_fitEditField.Value = '0';
            app.predictor_polynomial_orderEditFieldLabel = uilabel(app.predictorTab);
            app.predictor_polynomial_orderEditFieldLabel.Text = 'predictor_polynomial_order';
            app.predictor_polynomial_orderEditField = uieditfield(app.predictorTab, 'text');
            app.predictor_polynomial_orderEditField.Value = '1';
            app.correct_predictorDropDownLabel = uilabel(app.predictorTab);
            app.correct_predictorDropDownLabel.Text = 'correct_predictor';
            app.correct_predictorDropDown = uidropdown(app.predictorTab);
            app.correct_predictorDropDown.Items = {'true', 'false'};
            app.correct_predictorDropDown.Tooltip = {'flip sign of predictor if the sign of the path direction changes'};
            app.correct_predictorDropDown.Value = 'true';
            app.plotdisplayTab = uitab(app.TabGroup);
            app.plotdisplayTab.Title = 'plot & display';
            app.plotDropDownLabel = uilabel(app.plotdisplayTab);
            app.plotDropDownLabel.Text = 'plot';
            app.plotDropDown = uidropdown(app.plotdisplayTab);
            app.plotDropDown.Items = {'on', 'off', 'basic', 'detail', 'semilogx', 'semilogy', 'loglog'};
            app.plotDropDown.ValueChangedFcn = createCallbackFcn(app, @plotDropDownValueChanged, true);
            app.plotDropDown.Tooltip = {'Choose plot method'};
            app.plotDropDown.Value = 'on';
            app.plot_pauseEditFieldLabel = uilabel(app.plotdisplayTab);
            app.plot_pauseEditFieldLabel.Text = 'plot_pause';
            app.plot_pauseEditField = uieditfield(app.plotdisplayTab, 'text');
            app.plot_pauseEditField.Tooltip = {'Pause live plot until enter is pressed'};
            app.plot_pauseEditField.Value = 'false';
            app.plot_var_of_interestEditFieldLabel = uilabel(app.plotdisplayTab);
            app.plot_var_of_interestEditFieldLabel.Text = 'plot_var_of_interest';
            app.plot_var_of_interestEditField = uieditfield(app.plotdisplayTab, 'text');
            app.plot_var_of_interestEditField.Tooltip = {'Choose interesting variable'};
            app.plot_var_of_interestEditField.Value = 'NaN';
            app.plot_vars_indexEditField_2Label = uilabel(app.plotdisplayTab);
            app.plot_vars_indexEditField_2Label.Text = 'plot_vars_index';
            app.plot_vars_indexEditField_2 = uieditfield(app.plotdisplayTab, 'text');
            app.plot_vars_indexEditField_2.Tooltip = {'Choose variables to plot'};
            app.plot_vars_indexEditField_2.Value = '1:length(v0)';
            app.live_plot_figEditFieldLabel = uilabel(app.plotdisplayTab);
            app.live_plot_figEditFieldLabel.Text = 'live_plot_fig';
            app.live_plot_figEditField = uieditfield(app.plotdisplayTab, 'text');
            app.live_plot_figEditField.Tooltip = {'Choose a figure to plot in'};
            app.live_plot_figEditField.Value = 'NaN';
            app.displayDropDownLabel = uilabel(app.plotdisplayTab);
            app.displayDropDownLabel.Text = 'display';
            app.displayDropDown = uidropdown(app.plotdisplayTab);
            app.displayDropDown.Items = {'true', 'false'};
            app.displayDropDown.Tooltip = {'Show display progress in command window'};
            app.displayDropDown.Value = 'true';
            app.solverTab = uitab(app.TabGroup);
            app.solverTab.Title = 'solver';
            app.solverDropDownLabel = uilabel(app.solverTab);
            app.solverDropDownLabel.Text = 'solver';
            app.solverDropDown = uidropdown(app.solverTab);
            app.solverDropDown.Items = {'fsolve', 'fmincon', 'lsqnonlin', 'newton'};
            app.solverDropDown.Tooltip = {'determine solver-type'};
            app.solverDropDown.Value = 'fsolve';
            app.solver_force1itDropDownLabel = uilabel(app.solverTab);
            app.solver_force1itDropDownLabel.Text = 'solver_force1it';
            app.solver_force1itDropDown = uidropdown(app.solverTab);
            app.solver_force1itDropDown.Items = {'false', 'true'};
            app.solver_force1itDropDown.Tooltip = {'forces solver to do at least 1 iteration'};
            app.solver_force1itDropDown.Value = 'false';
            app.solver_tolEditFieldLabel = uilabel(app.solverTab);
            app.solver_tolEditFieldLabel.Text = 'solver_tol';
            app.solver_tolEditField = uieditfield(app.solverTab, 'text');
            app.solver_tolEditField.Tooltip = {'Define solver tolerance'};
            app.solver_tolEditField.Value = '1e-6';
            app.bifurcationTab = uitab(app.TabGroup);
            app.bifurcationTab.Tooltip = {'bifurcation analysis'};
            app.bifurcationTab.Title = 'bifurcation';
            app.bifurcationDropDownLabel = uilabel(app.bifurcationTab);
            app.bifurcationDropDownLabel.Text = 'bifurcation';
            app.bifurcationDropDown = uidropdown(app.bifurcationTab);
            app.bifurcationDropDown.Items = {'on', 'off', 'mark', 'trace', 'determine'};
            app.bifurcationDropDown.ValueChangedFcn = createCallbackFcn(app, @bifurcationDropDownValueChanged, true);
            app.bifurcationDropDown.Value = 'on';
            app.bif_rand_dirDropDownLabel = uilabel(app.bifurcationTab);
            app.bif_rand_dirDropDownLabel.Visible = 'off';
            app.bif_rand_dirDropDownLabel.Text = 'bif_rand_dir';
            app.bif_rand_dirDropDown = uidropdown(app.bifurcationTab);
            app.bif_rand_dirDropDown.Items = {'true', 'false'};
            app.bif_rand_dirDropDown.Visible = 'off';
            app.bif_rand_dirDropDown.Tooltip = {'ALso use random directions to find new branch at bifurcation point'};
            app.bif_rand_dirDropDown.Value = 'true';
            app.stop_on_bifurcationDropDownLabel = uilabel(app.bifurcationTab);
            app.stop_on_bifurcationDropDownLabel.Text = 'stop_on_bifurcation';
            app.stop_on_bifurcationDropDown = uidropdown(app.bifurcationTab);
            app.stop_on_bifurcationDropDown.Items = {'false', 'true'};
            app.stop_on_bifurcationDropDown.Tooltip = {'Force function to stop when point of bifurcation is found'};
            app.stop_on_bifurcationDropDown.Value = 'false';
            app.n_bif_searchEditFieldLabel = uilabel(app.bifurcationTab);
            app.n_bif_searchEditFieldLabel.Text = 'n_bif_search';
            app.n_bif_searchEditField = uieditfield(app.bifurcationTab, 'text');
            app.n_bif_searchEditField.Tooltip = {'maximum trials to find a new branch nearby a branch bifurcation'};
            app.n_bif_searchEditField.Value = '10';
            app.TabGroup2 = uitabgroup(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.TabGroup2.Visible = 'off';
            app.numericjacobianTab_2 = uitab(app.TabGroup2);
            app.numericjacobianTab_2.Title = 'numeric jacobian';
            app.diffquotDropDownLabel = uilabel(app.numericjacobianTab_2);
            app.diffquotDropDownLabel.Text = 'diffquot';
            app.diffquotDropDown = uidropdown(app.numericjacobianTab_2);
            app.diffquotDropDown.Items = {'forward', 'central'};
            app.diffquotDropDown.Tooltip = {'Difference quotient for calculation of numeric jacobian'};
            app.diffquotDropDown.Value = 'forward';
            app.reversecontrolTab = uitab(app.TabGroup2);
            app.reversecontrolTab.Title = 'reverse control';
            app.include_reverseDropDownLabel = uilabel(app.reversecontrolTab);
            app.include_reverseDropDownLabel.Text = 'include_reverse';
            app.include_reverseDropDown = uidropdown(app.reversecontrolTab);
            app.include_reverseDropDown.Items = {'false', 'true'};
            app.include_reverseDropDown.Value = 'false';
            app.reverseDropDownLabel = uilabel(app.reversecontrolTab);
            app.reverseDropDownLabel.Text = 'reverse';
            app.reverseDropDown = uidropdown(app.reversecontrolTab);
            app.reverseDropDown.Items = {'angle', 'jacobian'};
            app.reverseDropDown.Value = 'angle';
            app.alpha_reverseEditFieldLabel = uilabel(app.reversecontrolTab);
            app.alpha_reverseEditFieldLabel.Text = 'alpha_reverse';
            app.alpha_reverseEditField = uieditfield(app.reversecontrolTab, 'text');
            app.alpha_reverseEditField.Value = 'pi/2';
            app.scalingTab = uitab(app.TabGroup2);
            app.scalingTab.Title = 'scaling';
            app.scalingDropDownLabel = uilabel(app.scalingTab);
            app.scalingDropDownLabel.Text = 'scaling';
            app.scalingDropDown = uidropdown(app.scalingTab);
            app.scalingDropDown.Items = {'dynamicdscale', 'staticdscale'};
            app.scalingDropDown.ValueChangedFcn = createCallbackFcn(app, @scalingDropDownValueChanged, true);
            app.scalingDropDown.Tooltip = {'type of scaling'};
            app.scalingDropDown.Value = 'dynamicdscale';
            app.dscale0EditFieldLabel = uilabel(app.scalingTab);
            app.dscale0EditFieldLabel.Text = 'dscale0';
            app.dscale0EditField = uieditfield(app.scalingTab, 'text');
            app.dscale0EditField.Tooltip = {'starting value for scaling'};
            app.dscale0EditField.Value = 'ones(length(v0)+1,1)';
            app.dscale_minEditFieldLabel = uilabel(app.scalingTab);
            app.dscale_minEditFieldLabel.Text = 'dscale_min';
            app.dscale_minEditField = uieditfield(app.scalingTab, 'text');
            app.dscale_minEditField.Tooltip = {'minimal value for scaling'};
            app.dscale_minEditField.Value = '1e-5';
            app.breakTab = uitab(app.TabGroup2);
            app.breakTab.Title = 'break';
            app.break_functionEditFieldLabel = uilabel(app.breakTab);
            app.break_functionEditFieldLabel.Text = 'break_function';
            app.break_functionEditField = uieditfield(app.breakTab, 'text');
            app.break_functionEditField.Tooltip = {'Criterion for breaking the continuation'};
            app.break_functionEditField.Value = '@(f,J,v,l,break_fun_out) initial_break_function(f,J,v,l,break_fun_out)';
            app.initialdirectionTab_2 = uitab(app.TabGroup2);
            app.initialdirectionTab_2.Title = 'initial direction';
            app.directionEditFieldLabel = uilabel(app.initialdirectionTab_2);
            app.directionEditFieldLabel.Text = 'direction';
            app.directionEditField = uieditfield(app.initialdirectionTab_2, 'text');
            app.directionEditField.Tooltip = {'set initial direction'};
            app.directionEditField.Value = 'sign(l_end-l_start)*[zeros(size(v0));1]';
            app.closedcurvedetectionTab_2 = uitab(app.TabGroup2);
            app.closedcurvedetectionTab_2.Title = 'closed curve detection';
            app.closed_curve_detectionDropDownLabel = uilabel(app.closedcurvedetectionTab_2);
            app.closed_curve_detectionDropDownLabel.Text = 'closed_curve_detection';
            app.closed_curve_detectionDropDown = uidropdown(app.closedcurvedetectionTab_2);
            app.closed_curve_detectionDropDown.Items = {'false', 'true'};
            app.closed_curve_detectionDropDown.ValueChangedFcn = createCallbackFcn(app, @closed_curve_detectionDropDownValueChanged, true);
            app.closed_curve_detectionDropDown.Tooltip = {'Determine if continuation should end if a closed curve is found'};
            app.closed_curve_detectionDropDown.Value = 'false';
            app.closed_counterEditFieldLabel = uilabel(app.closedcurvedetectionTab_2);
            app.closed_counterEditFieldLabel.Visible = 'off';
            app.closed_counterEditFieldLabel.Text = 'closed_counter';
            app.closed_counterEditField = uieditfield(app.closedcurvedetectionTab_2, 'text');
            app.closed_counterEditField.Visible = 'off';
            app.closed_counterEditField.Tooltip = {'counter for closed curve detection'};
            app.closed_counterEditField.Value = '3';
            app.homotopyTab = uitab(app.TabGroup2);
            app.homotopyTab.Title = 'homotopy';
            app.homotopyDropDownLabel = uilabel(app.homotopyTab);
            app.homotopyDropDownLabel.Text = 'homotopy';
            app.homotopyDropDown = uidropdown(app.homotopyTab);
            app.homotopyDropDown.Items = {'off', 'fix', 'newton', 'f2', 'on', 'off'};
            app.homotopyDropDown.ValueChangedFcn = createCallbackFcn(app, @homotopyDropDownValueChanged, true);
            app.homotopyDropDown.Tooltip = {'determine homotopy-type'};
            app.homotopyDropDown.Value = 'off';
            app.homotopy_error_counterEditFieldLabel = uilabel(app.homotopyTab);
            app.homotopy_error_counterEditFieldLabel.Text = 'homotopy_error_counter';
            app.homotopy_error_counterEditField = uieditfield(app.homotopyTab, 'text');
            app.homotopy_error_counterEditField.Tooltip = {'set maximum number off errors in a row using homotopy'};
            app.homotopy_error_counterEditField.Value = '10';
            app.deflationTab = uitab(app.TabGroup2);
            app.deflationTab.Title = 'deflation';
            app.deflationDropDownLabel = uilabel(app.deflationTab);
            app.deflationDropDownLabel.Text = 'deflation';
            app.deflationDropDown = uidropdown(app.deflationTab);
            app.deflationDropDown.Items = {'true', 'false'};
            app.deflationDropDown.ValueChangedFcn = createCallbackFcn(app, @deflationDropDownValueChanged, true);
            app.deflationDropDown.Tooltip = {'set deflation on/off'};
            app.deflationDropDown.Value = 'true';
            app.deflation_error_counterEditFieldLabel = uilabel(app.deflationTab);
            app.deflation_error_counterEditFieldLabel.Text = 'deflation_error_counter';
            app.deflation_error_counterEditField = uieditfield(app.deflationTab, 'text');
            app.deflation_error_counterEditField.Tooltip = {'error counter for deflation'};
            app.deflation_error_counterEditField.Value = '5';
            app.advancedoptionsLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.advancedoptionsLabel.Text = 'advanced options';
            app.Function_nameEditFieldLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Function_nameEditFieldLabel.Tooltip = {'Enter the name of your function'};
            app.Function_nameEditFieldLabel.Text = 'Function_name';
            app.Image2 = uiimage(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Image2.ImageSource = 'icon.svg';
            app.Function_nameEditField = uieditfield(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'text');
            app.Function_nameEditField.Placeholder = 'Enter the name of your function';
            app.Function_nameEditField.Value = 'testfun02';
            app.Image3 = uiimage(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Image3.ImageSource = 'luh_1000.png';
            app.TextArea = uitextarea(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.TextArea.Visible = 'off';
            app.TextArea.Tooltip = {'Enter your function as code. Note that all variables must be initialized here.'};
            app.TextArea.Placeholder = 'Enter your function and needed variables...';
            app.ImplementfunctionbyDropDownLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.ImplementfunctionbyDropDownLabel.Tooltip = {'You can choose to implement the function by its path or as code.'};
            app.ImplementfunctionbyDropDownLabel.Text = 'Implement function by...';
            app.ImplementfunctionbyDropDown = uidropdown(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.ImplementfunctionbyDropDown.Items = {'Path', 'Function Handle'};
            app.ImplementfunctionbyDropDown.ValueChangedFcn = createCallbackFcn(app, @ImplementfunctionbyDropDownValueChanged, true);
            app.ImplementfunctionbyDropDown.Tooltip = {'You can choose to implement the function by its path or as code.'};
            app.ImplementfunctionbyDropDown.Value = 'Path';
            app.PathcontinuationtoolLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.PathcontinuationtoolLabel.Text = 'Path continuation tool';
            app.functionintegrationLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.functionintegrationLabel.Text = 'function integration';
            app.TextArea_2 = uitextarea(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.TextArea_2.Editable = 'off';
            app.commandwindownoteditableLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.commandwindownoteditableLabel.Text = '     command window (not editable)';
            app.EnablemenubarinplotCheckBox = uicheckbox(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.EnablemenubarinplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablemenubarinplotCheckBoxValueChanged, true);
            app.EnablemenubarinplotCheckBox.Tooltip = {'Enable menu bar in plot?'};
            app.EnablemenubarinplotCheckBox.Text = 'Enable menu bar in plot?';
            app.EnablemenubarinplotCheckBox.Value = true;
            app.EnabletoolbarinplotCheckBox = uicheckbox(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.EnabletoolbarinplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnabletoolbarinplotCheckBoxValueChanged, true);
            app.EnabletoolbarinplotCheckBox.Tooltip = {'Enable toolbar in plot?'};
            app.EnabletoolbarinplotCheckBox.Text = 'Enable toolbar in plot?';
            app.EnabletoolbarinplotCheckBox.Value = true;
            app.Button = uibutton(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'ids_1000.png';
            app.Button.Tooltip = {'Visit page'};
            app.Button.Text = '';
            app.ShowadvancedoptionsCheckBox = uicheckbox(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.ShowadvancedoptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowadvancedoptionsCheckBoxValueChanged2, true);
            app.ShowadvancedoptionsCheckBox.Tooltip = {'Show advanced options?'};
            app.ShowadvancedoptionsCheckBox.Text = 'Show advanced options?';
            app.GetfigureButton = uibutton(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'push');
            app.GetfigureButton.ButtonPushedFcn = createCallbackFcn(app, @GetfigureButtonPushed, true);
            app.GetfigureButton.Tooltip = {'Press to move figure to this place and to front'};
            app.GetfigureButton.Text = 'Get figure';
            app.ResetButton = uibutton(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResetButton_2 = uibutton(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.ResetButton_3 = uibutton(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            % Show the figure after all components are created
            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Continuation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure)
        end
    end
end